classdef TDT_GUI_v3_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        InputSignal1TX1DropDownLabel  matlab.ui.control.Label
        InputSignal1TX1DropDown       matlab.ui.control.DropDown
        OutputPortsFFPanel            matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        MapPortsButton                matlab.ui.control.Button
        OutputSelection1Panel         matlab.ui.container.Panel
        TX1FF1                        matlab.ui.control.CheckBox
        TX1FF2                        matlab.ui.control.CheckBox
        TX1FF3                        matlab.ui.control.CheckBox
        TX1FF4                        matlab.ui.control.CheckBox
        TX1FF8                        matlab.ui.control.CheckBox
        TX1FF7                        matlab.ui.control.CheckBox
        TX1FF6                        matlab.ui.control.CheckBox
        TX1FF5                        matlab.ui.control.CheckBox
        OptionsTX1Button              matlab.ui.control.Button
        MapOfRoomSpeakersButton       matlab.ui.control.Button
        StartButton                   matlab.ui.control.StateButton
        InputSignal2TX2DropDownLabel  matlab.ui.control.Label
        InputSignal2TX2DropDown       matlab.ui.control.DropDown
        OptionsTX2Button              matlab.ui.control.Button
        OutputSelection2Panel         matlab.ui.container.Panel
        TX2FF1                        matlab.ui.control.CheckBox
        TX2FF2                        matlab.ui.control.CheckBox
        TX2FF3                        matlab.ui.control.CheckBox
        TX2FF4                        matlab.ui.control.CheckBox
        TX2FF8                        matlab.ui.control.CheckBox
        TX2FF7                        matlab.ui.control.CheckBox
        TX2FF6                        matlab.ui.control.CheckBox
        TX2FF5                        matlab.ui.control.CheckBox
        OptionsTX3Button              matlab.ui.control.Button
        InputSignal3TX3DropDownLabel  matlab.ui.control.Label
        InputSignal3TX3DropDown       matlab.ui.control.DropDown
        OutputSelection3Panel         matlab.ui.container.Panel
        TX3FF1                        matlab.ui.control.CheckBox
        TX3FF2                        matlab.ui.control.CheckBox
        TX3FF3                        matlab.ui.control.CheckBox
        TX3FF4                        matlab.ui.control.CheckBox
        TX3FF8                        matlab.ui.control.CheckBox
        TX3FF7                        matlab.ui.control.CheckBox
        TX3FF6                        matlab.ui.control.CheckBox
        TX3FF5                        matlab.ui.control.CheckBox
        ExportButton                  matlab.ui.control.Button
        FlowExperimentTab             matlab.ui.container.Tab
        HereyouwillabletorunexpreimentLabel  matlab.ui.control.Label
        ImportExcelButton             matlab.ui.control.StateButton
        PlayPauseButton               matlab.ui.control.StateButton
        CalibrationTab                matlab.ui.container.Tab
        Speaker1SpinnerLabel          matlab.ui.control.Label
        Speaker1Spinner               matlab.ui.control.Spinner
        ImportButton                  matlab.ui.control.Button
        CalibExportButton             matlab.ui.control.Button
        Speaker2SpinnerLabel          matlab.ui.control.Label
        Speaker2Spinner               matlab.ui.control.Spinner
        Speaker3SpinnerLabel          matlab.ui.control.Label
        Speaker3Spinner               matlab.ui.control.Spinner
        Speaker4SpinnerLabel          matlab.ui.control.Label
        Speaker4Spinner               matlab.ui.control.Spinner
        Speaker8SpinnerLabel          matlab.ui.control.Label
        Speaker8Spinner               matlab.ui.control.Spinner
        Speaker7SpinnerLabel          matlab.ui.control.Label
        Speaker7Spinner               matlab.ui.control.Spinner
        Speaker6SpinnerLabel          matlab.ui.control.Label
        Speaker6Spinner               matlab.ui.control.Spinner
        Speaker5SpinnerLabel          matlab.ui.control.Label
        Speaker5Spinner               matlab.ui.control.Spinner
        Speaker9SpinnerLabel          matlab.ui.control.Label
        Speaker9Spinner               matlab.ui.control.Spinner
        Speaker10SpinnerLabel         matlab.ui.control.Label
        Speaker10Spinner              matlab.ui.control.Spinner
        Speaker11SpinnerLabel         matlab.ui.control.Label
        Speaker11Spinner              matlab.ui.control.Spinner
        Speaker12SpinnerLabel         matlab.ui.control.Label
        Speaker12Spinner              matlab.ui.control.Spinner
        Speaker13SpinnerLabel         matlab.ui.control.Label
        Speaker13Spinner              matlab.ui.control.Spinner
        Speaker14SpinnerLabel         matlab.ui.control.Label
        Speaker14Spinner              matlab.ui.control.Spinner
        Speaker15SpinnerLabel         matlab.ui.control.Label
        Speaker15Spinner              matlab.ui.control.Spinner
        Speaker16SpinnerLabel         matlab.ui.control.Label
        Speaker16Spinner              matlab.ui.control.Spinner
        Speaker17SpinnerLabel         matlab.ui.control.Label
        Speaker17Spinner              matlab.ui.control.Spinner
        Speaker18SpinnerLabel         matlab.ui.control.Label
        Speaker18Spinner              matlab.ui.control.Spinner
        FFOutput1EditFieldLabel       matlab.ui.control.Label
        FFOutput1EditField            matlab.ui.control.NumericEditField
        SpeakersLabel                 matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        FFOutput2EditFieldLabel       matlab.ui.control.Label
        FFOutput2EditField            matlab.ui.control.NumericEditField
        FFOutput4EditFieldLabel       matlab.ui.control.Label
        FFOutput4EditField            matlab.ui.control.NumericEditField
        FFOutput3EditFieldLabel       matlab.ui.control.Label
        FFOutput3EditField            matlab.ui.control.NumericEditField
        FFOutput5EditFieldLabel       matlab.ui.control.Label
        FFOutput5EditField            matlab.ui.control.NumericEditField
        FFOutput6EditFieldLabel       matlab.ui.control.Label
        FFOutput6EditField            matlab.ui.control.NumericEditField
        FFOutput8EditFieldLabel       matlab.ui.control.Label
        FFOutput8EditField            matlab.ui.control.NumericEditField
        FFOutput7EditFieldLabel       matlab.ui.control.Label
        FFOutput7EditField            matlab.ui.control.NumericEditField
        CalibExportButton_2           matlab.ui.control.Button
        ImportButton_2                matlab.ui.control.Button
        SNRFinderTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        expriementPool % For background expreiment 
        fileExperiment % future file expriement for parllell computing
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global RP;
            global whiteNoise_vector;
            % global parameters;
               
            % save parameters;


            path = strcat(pwd,'\Copy_of_full_3.rcx');
            [RP,status,message] = Circuit_Loader(path);
            statusNumbers = bitget(status,[1 2 3]);
            if(  any(0 == statusNumbers ) )
               idx = find(0==statusNumbers);
               numOfError = idx(1);
               if(1 == numOfError)
                  message = strcat(message,sprintf('\nPlease restart the DTD system'));
               end
               uiwait(msgbox(message,'Error','error'));
               close all force;
            
            end
            Initialize_Selection("TX1");
            Initialize_Selection("TX2");
            Initialize_Selection("TX3");
            whiteNoise_vector = randn(1,3000000);
        end

        % Button pushed function: MapPortsButton
        function MapPortsButtonPushed(app, event)
            I = imread('Maps/PP16Map.jpg');
            figure();
            imshow(I);
        end

        % Value changed function: InputSignal1TX1DropDown
        function InputSignal1TX1DropDownValueChanged(app, event)
            value = app.InputSignal1TX1DropDown.Value;
            DropValueChangedHelper(app,"TX1",value);
        end

        % Value changed function: TX1FF1
        function TX1FF1ValueChanged(app, event)
            value = app.TX1FF1.Value;
            DacVectorTransducerHelper(app,"TX1",1,value);
        end

        % Value changed function: TX1FF2
        function TX1FF2ValueChanged(app, event)
            value = app.TX1FF2.Value;
             DacVectorTransducerHelper(app,"TX1",2,value);
        end

        % Value changed function: TX1FF3
        function TX1FF3ValueChanged(app, event)
            value = app.TX1FF3.Value;
             DacVectorTransducerHelper(app,"TX1",3,value);
        end

        % Value changed function: TX1FF4
        function TX1FF4ValueChanged(app, event)
            value = app.TX1FF4.Value;
             DacVectorTransducerHelper(app,"TX1",4,value);
        end

        % Value changed function: TX1FF5
        function TX1FF5ValueChanged(app, event)
            value = app.TX1FF5.Value;
             DacVectorTransducerHelper(app,"TX1",5,value);
        end

        % Value changed function: TX1FF6
        function TX1FF6ValueChanged(app, event)
            value = app.TX1FF6.Value;
             DacVectorTransducerHelper(app,"TX1",6,value);
        end

        % Value changed function: TX1FF7
        function TX1FF7ValueChanged(app, event)
            value = app.TX1FF7.Value;
             DacVectorTransducerHelper(app,"TX1",7,value);
        end

        % Value changed function: TX1FF8
        function TX1FF8ValueChanged(app, event)
            value = app.TX1FF8.Value;
            DacVectorTransducerHelper(app,"TX1",8,value);
        end

        % Button pushed function: OptionsTX1Button
        function OptionsTX1ButtonPushed(app, event)
            OptionsMenuOpenerHelper(app,1);
        end

        % Button pushed function: MapOfRoomSpeakersButton
        function MapOfRoomSpeakersButtonPushed(app, event)
            top = imread('Maps/SpeakersTop.jpg');
            horizontal = imread('Maps/SpeakersLow.jpg');
            figure();
            subplot(1,2,1);
            imshow(top);
            title('Speakers Numbers in top level');
            subplot(1,2,2);
            imshow(horizontal);
            title('Speakers Numbers in horizontal level');
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            load mdb;
            value = app.StartButton.Value;
            if(1 == value )
                app.StartButton.Text = "Pause";
                [TX1_playMode,TX2_playMode,TX3_playMode] = play_signal_multi(mdb.master.TX1_select,mdb.master.TX2_select,mdb.master.TX3_select); 
            
                
            %TODO: check if needed    
            %     if (TX1_playMode &&  TX2_playMode &&  TX3_playMode)  %single shot
            %         set(hObject,'String','start'); %reset button
            %         set(hObject,'Value',0);
            %     end
            
            else
                app.StartButton.Text = "Start";
                TXall_stop_signal();
            end
            
        end

        % Value changed function: InputSignal2TX2DropDown
        function InputSignal2TX2DropDownValueChanged(app, event)
            value = app.InputSignal2TX2DropDown.Value;
            DropValueChangedHelper(app,"TX2",value);
        end

        % Button pushed function: OptionsTX2Button
        function OptionsTX2ButtonPushed(app, event)
              OptionsMenuOpenerHelper(app,2);
        end

        % Value changed function: TX2FF1
        function TX2FF1ValueChanged(app, event)
            value = app.TX2FF1.Value;
            DacVectorTransducerHelper(app,"TX2",1,value);
        end

        % Value changed function: TX2FF2
        function TX2FF2ValueChanged(app, event)
            value = app.TX2FF2.Value;
            DacVectorTransducerHelper(app,"TX2",2,value);
        end

        % Value changed function: TX2FF3
        function TX2FF3ValueChanged(app, event)
            value = app.TX2FF3.Value;
            DacVectorTransducerHelper(app,"TX2",3,value);
        end

        % Value changed function: TX2FF4
        function TX2FF4ValueChanged(app, event)
            value = app.TX2FF4.Value;
            DacVectorTransducerHelper(app,"TX2",4,value);
        end

        % Value changed function: TX2FF5
        function TX2FF5ValueChanged(app, event)
            value = app.TX2FF5.Value;
            DacVectorTransducerHelper(app,"TX2",5,value);
        end

        % Value changed function: TX2FF6
        function TX2FF6ValueChanged(app, event)
            value = app.TX2FF6.Value;
            DacVectorTransducerHelper(app,"TX2",6,value);
        end

        % Value changed function: TX2FF7
        function TX2FF7ValueChanged(app, event)
            value = app.TX2FF7.Value;
            DacVectorTransducerHelper(app,"TX2",7,value);
        end

        % Value changed function: TX2FF8
        function TX2FF8ValueChanged(app, event)
            value = app.TX2FF8.Value;
            DacVectorTransducerHelper(app,"TX2",8,value);
        end

        % Value changed function: TX3FF1
        function TX3FF1ValueChanged(app, event)
            value = app.TX3FF1.Value;
             DacVectorTransducerHelper(app,"TX3",1,value);
        end

        % Value changed function: TX3FF2
        function TX3FF2ValueChanged(app, event)
            value = app.TX3FF2.Value;
            DacVectorTransducerHelper(app,"TX3",2,value);
        end

        % Value changed function: TX3FF3
        function TX3FF3ValueChanged(app, event)
            value = app.TX3FF3.Value;
            DacVectorTransducerHelper(app,"TX3",3,value);
        end

        % Value changed function: TX3FF4
        function TX3FF4ValueChanged(app, event)
            value = app.TX3FF4.Value;
            DacVectorTransducerHelper(app,"TX3",4,value);
        end

        % Value changed function: TX3FF5
        function TX3FF5ValueChanged(app, event)
            value = app.TX3FF5.Value;
            DacVectorTransducerHelper(app,"TX3",5,value);
        end

        % Value changed function: TX3FF6
        function TX3FF6ValueChanged(app, event)
            value = app.TX3FF6.Value;
            DacVectorTransducerHelper(app,"TX3",6,value);
            
        end

        % Value changed function: TX3FF7
        function TX3FF7ValueChanged(app, event)
            value = app.TX3FF7.Value;
            DacVectorTransducerHelper(app,"TX3",7,value);
        end

        % Value changed function: TX3FF8
        function TX3FF8ValueChanged(app, event)
            value = app.TX3FF8.Value;
            DacVectorTransducerHelper(app,"TX3",8,value);
        end

        % Value changed function: InputSignal3TX3DropDown
        function InputSignal3TX3DropDownValueChanged(app, event)
            value = app.InputSignal3TX3DropDown.Value;
            DropValueChangedHelper(app,"TX3",value);

        end

        % Button pushed function: OptionsTX3Button
        function OptionsTX3ButtonPushed(app, event)
             OptionsMenuOpenerHelper(app,3);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
%             TODO Add Logic for export button
        end

        % Value changed function: ImportExcelButton
        function ImportExcelButtonValueChanged(app, event)
            value = app.ImportExcelButton.Value;
           [FileName,PathName,FilterIndex] = uigetfile('Documents\*.xlsx');
                   if(FileName == 0)
                       return;
                   end
           
           app.fileExperiment  = fullfile(PathName,FileName);
%            app.scriptExperiment = readcell(fullfile(PathName,FileName));
           app.ImportExcelButton.Text = FileName;
           app.PlayPauseButton.Enable = 1;
           
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(0 == value)
                %TODO make it non-blocking function https://www.mathworks.com/help/parallel-computing/parallel.pool.parfeval.html#mw_130ecf71-d0dc-485c-b230-99fc0e9107e7
                app.PlayPauseButton.Text = 'Pause';
                HandleExpriement(readcell(app.fileExperiment),app.PlayPauseButton);
%                 app.expriementPool = parfeval(@HandleExpriement,0, app.scriptExperiment,app.PlayPauseButton);
            elseif(1 == value)
                app.PlayPauseButton.Text = 'Start';
%                 cancel(app.expriementPool);
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.InputSignal1TX1DropDownLabel = uilabel(app.MainTab);
            app.InputSignal1TX1DropDownLabel.Text = 'Input Signal 1 (TX1)';
            app.InputSignal1TX1DropDown = uidropdown(app.MainTab);
            app.InputSignal1TX1DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal1TX1DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal1TX1DropDownValueChanged, true);
            app.InputSignal1TX1DropDown.Value = 'None';
            app.OutputPortsFFPanel = uipanel(app.MainTab);
            app.OutputPortsFFPanel.Title = 'Output Ports (FF)';
            app.Label = uilabel(app.OutputPortsFFPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.OutputPortsFFPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.OutputPortsFFPanel);
            app.Label_3.Text = '4';
            app.Label_4 = uilabel(app.OutputPortsFFPanel);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.OutputPortsFFPanel);
            app.Label_5.Text = '8';
            app.Label_6 = uilabel(app.OutputPortsFFPanel);
            app.Label_6.Text = '7';
            app.Label_7 = uilabel(app.OutputPortsFFPanel);
            app.Label_7.Text = '6';
            app.Label_8 = uilabel(app.OutputPortsFFPanel);
            app.Label_8.Text = '5';
            app.MapPortsButton = uibutton(app.OutputPortsFFPanel, 'push');
            app.MapPortsButton.ButtonPushedFcn = createCallbackFcn(app, @MapPortsButtonPushed, true);
            app.MapPortsButton.Text = 'Map';
            app.OutputSelection1Panel = uipanel(app.MainTab);
            app.OutputSelection1Panel.Title = 'Output Selection 1';
            app.TX1FF1 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF1.ValueChangedFcn = createCallbackFcn(app, @TX1FF1ValueChanged, true);
            app.TX1FF1.Text = '';
            app.TX1FF2 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF2.ValueChangedFcn = createCallbackFcn(app, @TX1FF2ValueChanged, true);
            app.TX1FF2.Text = '';
            app.TX1FF3 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF3.ValueChangedFcn = createCallbackFcn(app, @TX1FF3ValueChanged, true);
            app.TX1FF3.Text = '';
            app.TX1FF4 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF4.ValueChangedFcn = createCallbackFcn(app, @TX1FF4ValueChanged, true);
            app.TX1FF4.Text = '';
            app.TX1FF8 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF8.ValueChangedFcn = createCallbackFcn(app, @TX1FF8ValueChanged, true);
            app.TX1FF8.Text = '';
            app.TX1FF7 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF7.ValueChangedFcn = createCallbackFcn(app, @TX1FF7ValueChanged, true);
            app.TX1FF7.Text = '';
            app.TX1FF6 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF6.ValueChangedFcn = createCallbackFcn(app, @TX1FF6ValueChanged, true);
            app.TX1FF6.Text = '';
            app.TX1FF5 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF5.ValueChangedFcn = createCallbackFcn(app, @TX1FF5ValueChanged, true);
            app.TX1FF5.Text = '';
            app.OptionsTX1Button = uibutton(app.MainTab, 'push');
            app.OptionsTX1Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX1ButtonPushed, true);
            app.OptionsTX1Button.Enable = 'off';
            app.OptionsTX1Button.Text = 'Options';
            app.MapOfRoomSpeakersButton = uibutton(app.MainTab, 'push');
            app.MapOfRoomSpeakersButton.ButtonPushedFcn = createCallbackFcn(app, @MapOfRoomSpeakersButtonPushed, true);
            app.MapOfRoomSpeakersButton.Text = 'Map Of Room Speakers';
            app.StartButton = uibutton(app.MainTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.InputSignal2TX2DropDownLabel = uilabel(app.MainTab);
            app.InputSignal2TX2DropDownLabel.Text = 'Input Signal 2 (TX2)';
            app.InputSignal2TX2DropDown = uidropdown(app.MainTab);
            app.InputSignal2TX2DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal2TX2DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal2TX2DropDownValueChanged, true);
            app.InputSignal2TX2DropDown.Value = 'None';
            app.OptionsTX2Button = uibutton(app.MainTab, 'push');
            app.OptionsTX2Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX2ButtonPushed, true);
            app.OptionsTX2Button.Enable = 'off';
            app.OptionsTX2Button.Text = 'Options';
            app.OutputSelection2Panel = uipanel(app.MainTab);
            app.OutputSelection2Panel.Title = 'Output Selection 2';
            app.TX2FF1 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF1.ValueChangedFcn = createCallbackFcn(app, @TX2FF1ValueChanged, true);
            app.TX2FF1.Text = '';
            app.TX2FF2 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF2.ValueChangedFcn = createCallbackFcn(app, @TX2FF2ValueChanged, true);
            app.TX2FF2.Text = '';
            app.TX2FF3 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF3.ValueChangedFcn = createCallbackFcn(app, @TX2FF3ValueChanged, true);
            app.TX2FF3.Text = '';
            app.TX2FF4 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF4.ValueChangedFcn = createCallbackFcn(app, @TX2FF4ValueChanged, true);
            app.TX2FF4.Text = '';
            app.TX2FF8 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF8.ValueChangedFcn = createCallbackFcn(app, @TX2FF8ValueChanged, true);
            app.TX2FF8.Text = '';
            app.TX2FF7 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF7.ValueChangedFcn = createCallbackFcn(app, @TX2FF7ValueChanged, true);
            app.TX2FF7.Text = '';
            app.TX2FF6 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF6.ValueChangedFcn = createCallbackFcn(app, @TX2FF6ValueChanged, true);
            app.TX2FF6.Text = '';
            app.TX2FF5 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF5.ValueChangedFcn = createCallbackFcn(app, @TX2FF5ValueChanged, true);
            app.TX2FF5.Text = '';
            app.OptionsTX3Button = uibutton(app.MainTab, 'push');
            app.OptionsTX3Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX3ButtonPushed, true);
            app.OptionsTX3Button.Enable = 'off';
            app.OptionsTX3Button.Text = 'Options';
            app.InputSignal3TX3DropDownLabel = uilabel(app.MainTab);
            app.InputSignal3TX3DropDownLabel.Text = 'Input Signal 3 (TX3)';
            app.InputSignal3TX3DropDown = uidropdown(app.MainTab);
            app.InputSignal3TX3DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal3TX3DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal3TX3DropDownValueChanged, true);
            app.InputSignal3TX3DropDown.Value = 'None';
            app.OutputSelection3Panel = uipanel(app.MainTab);
            app.OutputSelection3Panel.Title = 'Output Selection 3';
            app.TX3FF1 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF1.ValueChangedFcn = createCallbackFcn(app, @TX3FF1ValueChanged, true);
            app.TX3FF1.Text = '';
            app.TX3FF2 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF2.ValueChangedFcn = createCallbackFcn(app, @TX3FF2ValueChanged, true);
            app.TX3FF2.Text = '';
            app.TX3FF3 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF3.ValueChangedFcn = createCallbackFcn(app, @TX3FF3ValueChanged, true);
            app.TX3FF3.Text = '';
            app.TX3FF4 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF4.ValueChangedFcn = createCallbackFcn(app, @TX3FF4ValueChanged, true);
            app.TX3FF4.Text = '';
            app.TX3FF8 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF8.ValueChangedFcn = createCallbackFcn(app, @TX3FF8ValueChanged, true);
            app.TX3FF8.Text = '';
            app.TX3FF7 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF7.ValueChangedFcn = createCallbackFcn(app, @TX3FF7ValueChanged, true);
            app.TX3FF7.Text = '';
            app.TX3FF6 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF6.ValueChangedFcn = createCallbackFcn(app, @TX3FF6ValueChanged, true);
            app.TX3FF6.Text = '';
            app.TX3FF5 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF5.ValueChangedFcn = createCallbackFcn(app, @TX3FF5ValueChanged, true);
            app.TX3FF5.Text = '';
            app.ExportButton = uibutton(app.MainTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FlowExperimentTab = uitab(app.TabGroup);
            app.FlowExperimentTab.Title = 'Flow Experiment';
            app.HereyouwillabletorunexpreimentLabel = uilabel(app.FlowExperimentTab);
            app.HereyouwillabletorunexpreimentLabel.Text = 'Here you will able to run automatic experiment that composed from severel setups/configurations of different inputs and speakers';
            app.ImportExcelButton = uibutton(app.FlowExperimentTab, 'state');
            app.ImportExcelButton.ValueChangedFcn = createCallbackFcn(app, @ImportExcelButtonValueChanged, true);
            app.ImportExcelButton.Text = 'Import Excel';
            app.PlayPauseButton = uibutton(app.FlowExperimentTab, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Enable = 'off';
            app.PlayPauseButton.Text = 'Start';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Speaker1SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker1SpinnerLabel.Text = 'Speaker 1';
            app.Speaker1Spinner = uispinner(app.CalibrationTab);
            app.ImportButton = uibutton(app.CalibrationTab, 'push');
            app.ImportButton.Text = 'Import';
            app.CalibExportButton = uibutton(app.CalibrationTab, 'push');
            app.CalibExportButton.Text = 'Export';
            app.Speaker2SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker2SpinnerLabel.Text = 'Speaker 2';
            app.Speaker2Spinner = uispinner(app.CalibrationTab);
            app.Speaker3SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker3SpinnerLabel.Text = 'Speaker 3';
            app.Speaker3Spinner = uispinner(app.CalibrationTab);
            app.Speaker4SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker4SpinnerLabel.Text = 'Speaker 4';
            app.Speaker4Spinner = uispinner(app.CalibrationTab);
            app.Speaker8SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker8SpinnerLabel.Text = 'Speaker 8';
            app.Speaker8Spinner = uispinner(app.CalibrationTab);
            app.Speaker7SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker7SpinnerLabel.Text = 'Speaker 7';
            app.Speaker7Spinner = uispinner(app.CalibrationTab);
            app.Speaker6SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker6SpinnerLabel.Text = 'Speaker 6';
            app.Speaker6Spinner = uispinner(app.CalibrationTab);
            app.Speaker5SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker5SpinnerLabel.Text = 'Speaker 5';
            app.Speaker5Spinner = uispinner(app.CalibrationTab);
            app.Speaker9SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker9SpinnerLabel.Text = 'Speaker 9';
            app.Speaker9Spinner = uispinner(app.CalibrationTab);
            app.Speaker10SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker10SpinnerLabel.Text = 'Speaker 10';
            app.Speaker10Spinner = uispinner(app.CalibrationTab);
            app.Speaker11SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker11SpinnerLabel.Text = 'Speaker 11';
            app.Speaker11Spinner = uispinner(app.CalibrationTab);
            app.Speaker12SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker12SpinnerLabel.Text = 'Speaker 12';
            app.Speaker12Spinner = uispinner(app.CalibrationTab);
            app.Speaker13SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker13SpinnerLabel.Text = 'Speaker 13';
            app.Speaker13Spinner = uispinner(app.CalibrationTab);
            app.Speaker14SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker14SpinnerLabel.Text = 'Speaker 14';
            app.Speaker14Spinner = uispinner(app.CalibrationTab);
            app.Speaker15SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker15SpinnerLabel.Text = 'Speaker 15';
            app.Speaker15Spinner = uispinner(app.CalibrationTab);
            app.Speaker16SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker16SpinnerLabel.Text = 'Speaker 16';
            app.Speaker16Spinner = uispinner(app.CalibrationTab);
            app.Speaker17SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker17SpinnerLabel.Text = 'Speaker 17';
            app.Speaker17Spinner = uispinner(app.CalibrationTab);
            app.Speaker18SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker18SpinnerLabel.Text = 'Speaker 18';
            app.Speaker18Spinner = uispinner(app.CalibrationTab);
            app.FFOutput1EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput1EditFieldLabel.Text = 'FF Output 1';
            app.FFOutput1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput1EditField.Limits = [1 18];
            app.FFOutput1EditField.Value = 1;
            app.SpeakersLabel = uilabel(app.CalibrationTab);
            app.SpeakersLabel.Text = 'Speakers';
            app.Label_9 = uilabel(app.CalibrationTab);
            app.Label_9.Text = {'Please let the software to know which'; ' speakers are connected to the '; 'output so the correct calibration '; 'numbers will be taken into account'};
            app.FFOutput2EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput2EditFieldLabel.Text = 'FF Output 2';
            app.FFOutput2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput2EditField.Limits = [1 18];
            app.FFOutput2EditField.Value = 1;
            app.FFOutput4EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput4EditFieldLabel.Text = 'FF Output 4';
            app.FFOutput4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput4EditField.Limits = [1 18];
            app.FFOutput4EditField.Value = 1;
            app.FFOutput3EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput3EditFieldLabel.Text = 'FF Output 3';
            app.FFOutput3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput3EditField.Limits = [1 18];
            app.FFOutput3EditField.Value = 1;
            app.FFOutput5EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput5EditFieldLabel.Text = 'FF Output 5';
            app.FFOutput5EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput5EditField.Limits = [1 18];
            app.FFOutput5EditField.Value = 1;
            app.FFOutput6EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput6EditFieldLabel.Text = 'FF Output 6';
            app.FFOutput6EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput6EditField.Limits = [1 18];
            app.FFOutput6EditField.Value = 1;
            app.FFOutput8EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput8EditFieldLabel.Text = 'FF Output 8';
            app.FFOutput8EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput8EditField.Limits = [1 18];
            app.FFOutput8EditField.Value = 1;
            app.FFOutput7EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput7EditFieldLabel.Text = 'FF Output 7';
            app.FFOutput7EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput7EditField.Limits = [1 18];
            app.FFOutput7EditField.Value = 1;
            app.CalibExportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.CalibExportButton_2.Text = 'Export';
            app.ImportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.ImportButton_2.Text = 'Import';
            app.SNRFinderTab = uitab(app.TabGroup);
            app.SNRFinderTab.Title = 'SNR Finder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDT_GUI_v3_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end